classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        SimulationAreaPanel           matlab.ui.container.Panel
        COMPLETED010Label             matlab.ui.control.Label
        DEADLOCKS0Label               matlab.ui.control.Label
        SIMTIME00sLabel               matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        FLEETMANAGERPROV40Panel_2     matlab.ui.container.Panel
        WaitLabel                     matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        ViewAnalysisReportButton      matlab.ui.control.Button
        SystemReadyYESLabel           matlab.ui.control.Label
        PausedNOLabel                 matlab.ui.control.Label
        SystemStatusLabel             matlab.ui.control.Label
        PlannedPathTraveledPathLabel  matlab.ui.control.Label
        ObstacleLabel                 matlab.ui.control.Label
        RobotLabel                    matlab.ui.control.Label
        LegendLabel                   matlab.ui.control.Label
        FLEETMANAGERPROV40Panel       matlab.ui.container.Panel
        SystemLogsPanel               matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        DropDown3                     matlab.ui.control.DropDown
        DropDown3Label                matlab.ui.control.Label
        DropDown2                     matlab.ui.control.DropDown
        DropDown2Label                matlab.ui.control.Label
        PlaceRobotsResetButton        matlab.ui.control.Button
        MapTypeButtonGroup            matlab.ui.container.ButtonGroup
        NarrowCorridorButton          matlab.ui.control.ToggleButton
        RandomMapButton               matlab.ui.control.ToggleButton
        DensitySlider                 matlab.ui.control.Slider
        DensitySliderLabel            matlab.ui.control.Label
        GiriEditField                 matlab.ui.control.EditField
        StudentIDSeedLabel            matlab.ui.control.Label
        EnvironmentSetupLabel         matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = public)
        Property4 % Description
        MapData     % binaryOccupancyMap nesnesi
        Robots      % Robotların konum ve renk bilgilerini tutan yapı
        Paths       % Planlanan yolları saklamak için
        RobotVelocities %
        RobotPlots   % robot marker handle'ları
        SimStartTime datetime
        DeadlockCount double = 0
        CompletedCount double = 0
        TraveledPaths  % her robotun gerçekten gittiği yol
    end
    
    properties (Access = private)
        Property8 % Description
    end
    
    methods (Access = private)
        
        
       function pathRC = bfsGrid(app, occGrid, startRC, goalRC)
            % occGrid: 1=engel, 0=free (logical)
        % startRC / goalRC: [row col]

        nR = size(occGrid,1);
        nC = size(occGrid,2);

        % --- Güvenlik kontrolleri ---
        if any(startRC < 1) || startRC(1)>nR || startRC(2)>nC
            pathRC = []; return;
        end
        if any(goalRC < 1) || goalRC(1)>nR || goalRC(2)>nC
            pathRC = []; return;
        end
        if occGrid(startRC(1),startRC(2)) || occGrid(goalRC(1),goalRC(2))
            pathRC = []; return;
        end

        visited = false(nR,nC);
        parentR = zeros(nR,nC,'int16');
        parentC = zeros(nR,nC,'int16');

        % --- Kuyruk ---
        qR = zeros(nR*nC,1,'int16');
        qC = zeros(nR*nC,1,'int16');
        head = 1; tail = 1;

        qR(tail) = startRC(1);
        qC(tail) = startRC(2);
        visited(startRC(1),startRC(2)) = true;

        % 4-komşu
        dR = int16([-1 1 0 0]);
        dC = int16([0 0 -1 1]);

        found = false;

        while head <= tail
            r = qR(head);
            c = qC(head);
            head = head + 1;

            if r == goalRC(1) && c == goalRC(2)
                found = true;
                break;
            end

            for k = 1:4
                nr = r + dR(k);
                nc = c + dC(k);

                if nr>=1 && nr<=nR && nc>=1 && nc<=nC ...
                        && ~visited(nr,nc) && ~occGrid(nr,nc)

                    visited(nr,nc) = true;
                    parentR(nr,nc) = r;
                    parentC(nr,nc) = c;

                    tail = tail + 1;
                    qR(tail) = nr;
                    qC(tail) = nc;
                end
            end
        end

        if ~found
            pathRC = [];
            return;
        end

        % --- Geri izleme (goal → start) ---
        r = goalRC(1);
        c = goalRC(2);
        rr = [];
        cc = [];

        while ~(r == startRC(1) && c == startRC(2))
            rr(end+1) = r; %#ok<AGROW>
            cc(end+1) = c;

            pr = parentR(r,c);
            pc = parentC(r,c);
            r = pr; c = pc;
        end

        rr(end+1) = startRC(1);
        cc(end+1) = startRC(2);

        rr = flip(rr);
        cc = flip(cc);

        pathRC = [rr(:) cc(:)];
   

        end
        
       function pathRC = dijkstraGrid(app, occGrid, startRC, goalRC)

                   % occGrid: logical (1=engel, 0=free)
        % startRC, goalRC: [row col]
        % çıktı: pathRC (Nx2) veya []
        
            occGrid = occGrid > 0;
            nR = size(occGrid,1);
            nC = size(occGrid,2);
        
            % --- Güvenlik ---
            if any(startRC < 1) || startRC(1)>nR || startRC(2)>nC
                pathRC = []; return;
            end
            if any(goalRC < 1) || goalRC(1)>nR || goalRC(2)>nC
                pathRC = []; return;
            end
            if occGrid(startRC(1),startRC(2)) || occGrid(goalRC(1),goalRC(2))
                pathRC = []; return;
            end
        
            % --- Dijkstra yapıları ---
            dist = inf(nR,nC);
            visited = false(nR,nC);
            parentR = zeros(nR,nC,'int16');
            parentC = zeros(nR,nC,'int16');
        
            dist(startRC(1),startRC(2)) = 0;
        
            % 4-komşu
            dR = [-1 1 0 0];
            dC = [0 0 -1 1];
        
            while true
                % --- En küçük mesafeli node'u seç ---
                minDist = inf;
                cur = [-1 -1];
        
                for r = 1:nR
                    for c = 1:nC
                        if ~visited(r,c) && dist(r,c) < minDist
                            minDist = dist(r,c);
                            cur = [r c];
                        end
                    end
                end
        
                if cur(1) == -1
                    break; % ulaşılabilir node kalmadı
                end
        
                r = cur(1);
                c = cur(2);
        
                if r == goalRC(1) && c == goalRC(2)
                    break; % hedefe ulaşıldı
                end
        
                visited(r,c) = true;
        
                % --- Komşuları gevşet ---
                for k = 1:4
                    nr = r + dR(k);
                    nc = c + dC(k);
        
                    if nr>=1 && nr<=nR && nc>=1 && nc<=nC ...
                       && ~occGrid(nr,nc) && ~visited(nr,nc)
        
                        alt = dist(r,c) + (nr~=r && nc~=c)*1.5 + 1;

        
                        if alt < dist(nr,nc)
                            dist(nr,nc) = alt;
                            parentR(nr,nc) = r;
                            parentC(nr,nc) = c;
                        end
                    end
                end
            end
        
            % --- Yol yok ---
            if isinf(dist(goalRC(1),goalRC(2)))
                pathRC = [];
                return;
            end
        
            % --- Geri izleme ---
            r = goalRC(1);
            c = goalRC(2);
            rr = [];
            cc = [];
        
            while ~(r == startRC(1) && c == startRC(2))
                rr(end+1) = r; %#ok<AGROW>
                cc(end+1) = c;
        
                pr = parentR(r,c);
                pc = parentC(r,c);
                r = pr; c = pc;
            end
        
            rr(end+1) = startRC(1);
            cc(end+1) = startRC(2);
        
            rr = flip(rr);
            cc = flip(cc);
        
            pathRC = [rr(:) cc(:)];
         end
        
   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    app.GiriEditField.Placeholder = 'e.g. 230201032';
    app.TextArea.Value = {'[0.0s] System Ready'};
   
        end

        % Button pushed function: PlaceRobotsResetButton
        function PlaceRobotsResetButtonPushed4(app, event)

    %% LOG
    app.TextArea.Value = [app.TextArea.Value; ...
        "[Sim] Harita ve robotlar sıfırlanıyor..."];

    %% SEED
    seed = str2double(app.GiriEditField.Value);
    if ~isnan(seed)
        rng(seed);
    end

    %% MAP OLUŞTUR
    mapSize = 50;
    app.MapData = binaryOccupancyMap(mapSize, mapSize, 1);

    %% RANDOM / NARROW MAP
    if app.RandomMapButton.Value
        density = app.DensitySlider.Value / 100;
        occ = rand(mapSize, mapSize) < density;
        setOccupancy(app.MapData, occ);

    else
        % ===== NARROW CORRIDOR (T + ÇOKLU DİKEY KOL) =====
        corridorWidth = 4;
        occ = true(mapSize, mapSize);   % her yer duvar

        midY = round(mapSize/2);

        % ANA YATAY KORİDOR
        occ(midY-floor(corridorWidth/2):midY+floor(corridorWidth/2), :) = false;

        % DİKEY KOLLAR
        xPositions = [12, 25, 38];
        for x = xPositions
            occ(:, x-floor(corridorWidth/2):x+floor(corridorWidth/2)) = false;
        end

        setOccupancy(app.MapData, occ);
    end

    %% AXES TEMİZLE & HARİTA ÇİZ
    cla(app.UIAxes);
    show(app.MapData, 'Parent', app.UIAxes);
    hold(app.UIAxes, 'on');
    axis(app.UIAxes, 'equal');
    app.UIAxes.XTick = [];
    app.UIAxes.YTick = [];

    %% FREE CELLS (KORİDOR İÇİ)
    occMat = occupancyMatrix(app.MapData) > 0.5; % duvar=1
    freeCells = find(~occMat);
    [yList, xList] = ind2sub(size(occMat), freeCells);

    %% ROBOTLARI YERLEŞTİR
    numRobots = 10;
    colors = lines(numRobots);

    app.Robots = struct('start', {}, 'goal', {}, 'color', {});
    app.RobotPlots = gobjects(1, numRobots);

    for i = 1:numRobots
        valid = false;

        while ~valid
            idx = randi(numel(xList));
            s = [xList(idx), yList(idx)];

            idx = randi(numel(xList));
            g = [xList(idx), yList(idx)];

            if norm(s - g) > 10
                valid = true;
            end
        end

        app.Robots(i).start = s;
        app.Robots(i).goal  = g;
        app.Robots(i).color = colors(i,:);
        app.Robots(i).completed = false;


        % START
        app.RobotPlots(i) = plot(app.UIAxes, s(1), s(2), 'o', ...
            'MarkerFaceColor', colors(i,:), ...
            'Color', colors(i,:), ...
            'MarkerSize', 6);

        % GOAL
        plot(app.UIAxes, g(1), g(2), 'x', ...
            'Color', colors(i,:), ...
            'LineWidth', 2);
    end

    %% LOG
    app.TextArea.Value = [app.TextArea.Value; ...
        "[Sim] Robotlar yerleştirildi."];


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
% Button pushed function: StartButton
disp(class(app.MapData))
 numRobots = length(app.Robots); 
app.TraveledPaths = cell(1, numRobots);
for i = 1:numRobots
    app.TraveledPaths{i} = [];
end


     app.SimStartTime = datetime('now');
    app.DeadlockCount = 0;
    app.CompletedCount = 0;
    
    app.SIMTIME00sLabel.Text = "SIM TIME: 0.0 s";
    app.DEADLOCKS0Label.Text = "DEADLOCKS: 0";
    app.COMPLETED010Label.Text = ...
        "COMPLETED: 0 / " + num2str(length(app.Robots));

    app.TextArea.Value = [app.TextArea.Value; "[Sim] Yol planlama başlıyor..."];

    % =====================================================
    % DEADLOCK STRATEJİSİ SEÇİMİ (DropDown3)
    % =====================================================
    switch app.DropDown3.Value
        case 'Reactive Re-planning'
            app.TextArea.Value = [app.TextArea.Value; ...
                "[Deadlock] Reactive Re-planning aktif"];

        case 'Cooperative A*'
            app.TextArea.Value = [app.TextArea.Value; ...
                "[Deadlock] Cooperative A* aktif"];

        case 'Wait-For Graph'
            app.TextArea.Value = [app.TextArea.Value; ...
                "[Deadlock] Wait-For Graph aktif"];
    end

    numRobots = length(app.Robots);
    app.Paths = cell(1, numRobots);
    app.RobotVelocities = cell(1, numRobots);

    % =====================================================
    % HARİTAYI GRID FORMATINA ÇEVİR
    % =====================================================
 
    occGrid = occupancyMatrix(app.MapData);
    occGrid = occGrid > 0.5;   % 1 = obstacle, 0 = free

    % =====================================================
    % PLANLAMA (DropDown2)
    % =====================================================
 for i = 1:numRobots

    % ===== 1) START / GOAL (ROW,COL) HESAPLA =====
    startRC = round([app.Robots(i).start(2), app.Robots(i).start(1)]);
    goalRC  = round([app.Robots(i).goal(2),  app.Robots(i).goal(1)]);

    % ===== 2) GRID'i (BFS için) HAZIRLA =====
    occGrid = occupancyMatrix(app.MapData) > 0.5;  % 1=engel, 0=free
    mapSize = size(occGrid, 1);

    % ===== 3) SINIR KORUMASI =====
    startRC = max(startRC, [1 1]);
    goalRC  = max(goalRC,  [1 1]);

    startRC = min(startRC, [mapSize mapSize]);
    goalRC  = min(goalRC,  [mapSize mapSize]);

    % ===== 4) START/GOAL ENGEL ÜSTÜNDE Mİ? =====
    if occGrid(startRC(1), startRC(2)) || occGrid(goalRC(1), goalRC(2))
        app.TextArea.Value = [app.TextArea.Value; ...
            "Robot " + i + ": Start/Goal engel üzerinde -> atlandı"];
        continue;
    end

try
    % ===== PLANNER SEÇ ve PATH ÜRET =====
    switch app.DropDown2.Value

        case 'A* Optimal'
            planner = plannerAStarGrid(app.MapData);
            pathRC  = plan(planner, startRC, goalRC);

        case 'Dijkstra'
            % Senin MATLAB sürümünde plannerAStarGrid’de Heuristic yok.
            % O yüzden "Dijkstra"yı bizim Dijkstra olarak çalıştıracağız.
            % (Uniform gridde Dijkstra = BFS maliyet mantığı)
             pathRC = app.dijkstraGrid(occGrid, startRC, goalRC);

        case 'BFS'
            pathRC = app.bfsGrid(occGrid, startRC, goalRC);
    end

    % ===== PATH VARSA ÇİZ =====
    if ~isempty(pathRC)
        pathXY = [pathRC(:,2), pathRC(:,1)];
        app.Paths{i} = pathXY;

        plot(app.UIAxes, pathXY(:,1), pathXY(:,2), '--', ...
            'Color', app.Robots(i).color);

        app.RobotVelocities{i} = zeros(size(pathXY,1),1);
    else
        app.TextArea.Value = [app.TextArea.Value; ...
            "Robot " + i + ": Yol bulunamadı"];
    end

catch ME
    app.TextArea.Value = [app.TextArea.Value; ...
        "Robot " + i + ": Planlama hatası -> " + string(ME.message)];
end

end


    % =====================================================
    % HAREKET + DEADLOCK YÖNETİMİ
    % =====================================================
    validPaths = find(~cellfun(@isempty, app.Paths));
    if isempty(validPaths)
        app.TextArea.Value = [app.TextArea.Value; ...
            "[Sim] Hiçbir robot için yol yok!"];
        return;
    end

    maxLen = max(cellfun(@(p) size(p,1), app.Paths(validPaths)));
    app.TextArea.Value = [app.TextArea.Value; "[Sim] Hareket başlıyor..."];

    for t = 1:maxLen

        positions = nan(numRobots,2);

        % --- O ANKİ POZİSYONLAR ---
        for i = validPaths
            if t <= size(app.Paths{i},1)
                positions(i,:) = app.Paths{i}(t,:);
            end
        end

        % =================================================
        % REACTIVE RE-PLANNING
        % =================================================
        if strcmp(app.DropDown3.Value,'Reactive Re-planning')

            for i = validPaths
                for j = validPaths
                    if i ~= j && all(positions(i,:) == positions(j,:))

                        app.TextArea.Value = [app.TextArea.Value; ...
                            "[Reactive] Robot " + i + " yeniden planlıyor"];
                        app.DeadlockCount = app.DeadlockCount + 1;
                        app.DEADLOCKS0Label.Text = ...
                            "DEADLOCKS: " + num2str(app.DeadlockCount);


                        startRC = round([positions(i,2), positions(i,1)]);
                        goalRC  = round([app.Robots(i).goal(2), app.Robots(i).goal(1)]);

                        planner = plannerAStarGrid(app.MapData);
                        newRC   = plan(planner, startRC, goalRC);

                        if ~isempty(newRC)
                           newPathXY = [newRC(:,2), newRC(:,1)];
                            if size(newPathXY,1) < t
                                newPathXY(end+1:t,:) = repmat(newPathXY(end,:), t-size(newPathXY,1),1);
                            end
                            app.Paths{i} = newPathXY;

                        end
                    end
                end
            end
        end

        % =================================================
        % WAIT-FOR GRAPH
        % =================================================
        if strcmp(app.DropDown3.Value,'Wait-For Graph')

            for i = validPaths
                for j = validPaths
                    if i ~= j && ...
                       t <= size(app.Paths{i},1) && ...
                       t <= size(app.Paths{j},1)

                        if all(app.Paths{i}(t,:) == app.Paths{j}(t,:))

                            if i > j
                                % Robot i bekliyor, Robot j geçiyor
                                pos_i = app.Paths{i}(t,:);   % bekleyen
                                pos_j = app.Paths{j}(t,:);   % sebep olan
                                
                                % Ok vektörü
                                dx = pos_j(1) - pos_i(1);
                                dy = pos_j(2) - pos_i(2);
                                
                                % Wait-For Graph oku çiz
                                quiver(app.UIAxes, ...
                                    pos_i(1), pos_i(2), ...
                                    dx, dy, ...
                                    0, ...
                                    'Color','y', ...
                                    'LineWidth',2, ...
                                    'MaxHeadSize',0.8);

                                app.Paths{i}(t:end,:) = repmat( ...
                                    app.Paths{i}(t,:), ...
                                    size(app.Paths{i},1)-t+1, 1);

                                app.TextArea.Value = [app.TextArea.Value; ...
                                    "[Wait-For] Robot " + i + " bekliyor"];
                                     app.DeadlockCount = app.DeadlockCount + 1;
                                    app.DEADLOCKS0Label.Text = ...
                                        "DEADLOCKS: " + num2str(app.DeadlockCount);

                            end
                        end
                    end
                end
            end
        end

        % =================================================
        % POZİSYON GÜNCELLE
        % =================================================
for i = validPaths
    if t <= size(app.Paths{i},1)

        pos = app.Paths{i}(t,:);

        % --- ENGEL KONTROLÜ ---
        r = round(pos(2));
        c = round(pos(1));

        if r < 1 || r > size(occGrid,1) || ...
           c < 1 || c > size(occGrid,2) || ...
           occGrid(r,c)

            app.TextArea.Value = [app.TextArea.Value; ...
                "[ERROR] Robot " + i + " engel hücresine girdi!"];

            % Robotu DURDUR (hareket etmesin)
            continue;
        end

        % --- GÜVENLİ → POZİSYON GÜNCELLE ---
        set(app.RobotPlots(i),'XData',pos(1),'YData',pos(2));

        % Gerçek gidilen yolu kaydet
        app.TraveledPaths{i}(end+1,:) = pos;
    end
end

       
        % =================================================
% COMPLETED (HEDEFE VARAN ROBOT)
% =================================================
for i = validPaths
    if ~app.Robots(i).completed && t == size(app.Paths{i},1)

        app.Robots(i).completed = true;
        app.CompletedCount = app.CompletedCount + 1;

        app.COMPLETED010Label.Text = ...
            "COMPLETED: " + app.CompletedCount + ...
            " / " + length(app.Robots);
    end
end


       elapsed = seconds(datetime('now') - app.SimStartTime);
       app.SIMTIME00sLabel.Text = ...
       "SIM TIME: " + sprintf("%.1f", elapsed) + " s";

        drawnow limitrate
        pause(0.1)
    end

    app.TextArea.Value = [app.TextArea.Value; ...
        "[Sim] Hareket tamamlandı."];

        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
    selectedPlanner = app.DropDown2.Value;

    app.TextArea.Value = [
        app.TextArea.Value
        "[UI] Path Planner seçildi: " + selectedPlanner
    ];

        end

        % Value changed function: DropDown3
        function DropDown3ValueChanged(app, event)
             selectedMethod = app.DropDown3.Value;
    app.TextArea.Value = [
        app.TextArea.Value
        "[UI] Deadlock yöntemi seçildi: " + selectedMethod
    ];
        end

        % Selection changed function: MapTypeButtonGroup
        function MapTypeButtonGroupSelectionChanged(app, event)

    selectedButton = event.NewValue;

    if selectedButton == app.RandomMapButton
        app.TextArea.Value = [app.TextArea.Value; ...
            "[UI] Map Type seçildi: Random Map"];

    elseif selectedButton == app.NarrowCorridorButton
        app.TextArea.Value = [app.TextArea.Value; ...
            "[UI] Map Type seçildi: Narrow Corridor"];
    end

        end

        % Button pushed function: ViewAnalysisReportButton
        function ViewAnalysisReportButtonPushed(app, event)

if isempty(app.Paths)
    uialert(app.UIFigure,'Önce simülasyonu çalıştırmalısın!','Analysis Error');
    return;
end

numRobots = length(app.Paths);

dt = 0.1;     % pause ile AYNI
R  = 0.05;    % tekerlek yarıçapı
L  = 0.30;    % dingil mesafesi


    rows = ceil(sqrt(numRobots));
    cols = ceil(numRobots / rows);

 
    numRobots = length(app.Paths);

    % =========================
    % TEK FIGURE – TEK HARİTA
    % =========================
    figure('Name','Planned vs Traveled Paths (All Robots)', ...
           'NumberTitle','off');

    % Haritayı arka plana çiz
    show(app.MapData);
    hold on;
    axis equal;
    grid on;
    title('Planned vs Traveled Paths (All Robots)');

    % =========================
    % ROBOT YOLLARINI ÇİZ
    % =========================
    for i = 1:numRobots

        if isempty(app.Paths{i})
            continue;
        end

        % -------- Planned Path --------
        plot(app.Paths{i}(:,1), app.Paths{i}(:,2), ...
            '--', ...
            'LineWidth', 1.8, ...
            'Color', app.Robots(i).color);

        % -------- Traveled Path --------
        if ~isempty(app.TraveledPaths{i})
            plot(app.TraveledPaths{i}(:,1), app.TraveledPaths{i}(:,2), ...
                '-', ...
                'LineWidth', 2.4, ...
                'Color', app.Robots(i).color);
        end
    end

    % =========================
    % LEGEND (tek sefer)
    % =========================
    plot(nan, nan, 'k--', 'LineWidth', 1.8);
    plot(nan, nan, 'k-',  'LineWidth', 2.4);
    legend({'Planned Path','Traveled Path'}, ...
           'Location','bestoutside');

for i = 1:length(app.Paths)

    path = app.Paths{i};
    if isempty(path) || size(path,1) < 3
        continue;
    end

    dx = diff(path(:,1));
    dy = diff(path(:,2));

    v = sqrt(dx.^2 + dy.^2) / dt;

    theta  = atan2(dy, dx);
    dtheta = diff(theta);
    dtheta = atan2(sin(dtheta), cos(dtheta));  % 🔥 kritik
    omega  = dtheta / dt;

    N = min(length(v), length(omega));
    v     = v(1:N);
    omega = omega(1:N);

    vL = (2*v - omega*L) / (2*R);
    vR = (2*v + omega*L) / (2*R);

    t = (0:N-1)*dt;

    figure('Name',"Robot "+i+" - Analysis",'NumberTitle','off');

    subplot(3,1,1)
    plot(t,v,'b','LineWidth',2)
    title("Doğrusal Hız v(t)")
    ylabel("m/s"), grid on

    subplot(3,1,2)
    plot(t,omega,'r','LineWidth',2)
    title("Açısal Hız ω(t)")
    ylabel("rad/s"), grid on

    subplot(3,1,3)
    plot(t,vL,'g',t,vR,'m','LineWidth',2)
    title("Tekerlek Hızları")
    xlabel("Zaman (s)")
    ylabel("rad/s")
    legend("Sol","Sağ")
    grid on
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FLEETMANAGERPROV40Panel = uipanel(app.GridLayout);
            app.FLEETMANAGERPROV40Panel.Title = 'FLEETMANAGER PRO V4.0';
            app.EnvironmentSetupLabel = uilabel(app.FLEETMANAGERPROV40Panel);
            app.EnvironmentSetupLabel.Interpreter = 'tex';
            app.EnvironmentSetupLabel.Text = 'Environment Setup';
            app.StudentIDSeedLabel = uilabel(app.FLEETMANAGERPROV40Panel);
            app.StudentIDSeedLabel.Interpreter = 'tex';
            app.StudentIDSeedLabel.Text = 'Student ID / Seed';
            app.GiriEditField = uieditfield(app.FLEETMANAGERPROV40Panel, 'text');
            app.DensitySliderLabel = uilabel(app.FLEETMANAGERPROV40Panel);
            app.DensitySliderLabel.Text = 'Density (%)';
            app.DensitySlider = uislider(app.FLEETMANAGERPROV40Panel);
            app.DensitySlider.MajorTicks = [0 25 50 75 100];
            app.DensitySlider.Tooltip = {'Obstacle Density'};
            app.DensitySlider.Value = 30;
            app.MapTypeButtonGroup = uibuttongroup(app.FLEETMANAGERPROV40Panel);
            app.MapTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MapTypeButtonGroupSelectionChanged, true);
            app.MapTypeButtonGroup.Title = 'Map Type';
            app.RandomMapButton = uitogglebutton(app.MapTypeButtonGroup);
            app.RandomMapButton.Text = 'Random Map';
            app.RandomMapButton.Value = true;
            app.NarrowCorridorButton = uitogglebutton(app.MapTypeButtonGroup);
            app.NarrowCorridorButton.Text = 'Narrow Corridor';
            app.PlaceRobotsResetButton = uibutton(app.FLEETMANAGERPROV40Panel, 'push');
            app.PlaceRobotsResetButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceRobotsResetButtonPushed4, true);
            app.PlaceRobotsResetButton.Text = 'Place Robots (Reset)';
            app.DropDown2Label = uilabel(app.FLEETMANAGERPROV40Panel);
            app.DropDown2Label.Text = 'Drop Down2';
            app.DropDown2 = uidropdown(app.FLEETMANAGERPROV40Panel);
            app.DropDown2.Items = {'A* Optimal', 'Dijkstra', 'BFS'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Value = 'A* Optimal';
            app.DropDown3Label = uilabel(app.FLEETMANAGERPROV40Panel);
            app.DropDown3Label.Text = 'Drop Down3';
            app.DropDown3 = uidropdown(app.FLEETMANAGERPROV40Panel);
            app.DropDown3.Items = {'Reactive Re-planning', 'Cooperative A*', 'Wait-For Graph'};
            app.DropDown3.ValueChangedFcn = createCallbackFcn(app, @DropDown3ValueChanged, true);
            app.DropDown3.Value = 'Reactive Re-planning';
            app.SystemLogsPanel = uipanel(app.FLEETMANAGERPROV40Panel);
            app.SystemLogsPanel.Title = 'System Logs';
            app.TextArea = uitextarea(app.SystemLogsPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'[0.0s] System Ready'; '[0.2s] Map Generated'; '[0.5s] Robots Placed'};
            app.FLEETMANAGERPROV40Panel_2 = uipanel(app.GridLayout);
            app.FLEETMANAGERPROV40Panel_2.Title = 'FLEETMANAGER PRO V4.0';
            app.LegendLabel = uilabel(app.FLEETMANAGERPROV40Panel_2);
            app.LegendLabel.Text = 'Legend';
            app.RobotLabel = uilabel(app.FLEETMANAGERPROV40Panel_2);
            app.RobotLabel.Text = '● Robot';
            app.ObstacleLabel = uilabel(app.FLEETMANAGERPROV40Panel_2);
            app.ObstacleLabel.Text = '■ Obstacle';
            app.PlannedPathTraveledPathLabel = uilabel(app.FLEETMANAGERPROV40Panel_2);
            app.PlannedPathTraveledPathLabel.Text = {'- - - Planned Path'; '── Traveled Path'};
            app.SystemStatusLabel = uilabel(app.FLEETMANAGERPROV40Panel_2);
            app.SystemStatusLabel.Text = 'System Status';
            app.PausedNOLabel = uilabel(app.FLEETMANAGERPROV40Panel_2);
            app.PausedNOLabel.Text = 'Paused: NO';
            app.SystemReadyYESLabel = uilabel(app.FLEETMANAGERPROV40Panel_2);
            app.SystemReadyYESLabel.Text = 'System Ready: YES';
            app.ViewAnalysisReportButton = uibutton(app.FLEETMANAGERPROV40Panel_2, 'push');
            app.ViewAnalysisReportButton.ButtonPushedFcn = createCallbackFcn(app, @ViewAnalysisReportButtonPushed, true);
            app.ViewAnalysisReportButton.Text = 'View Analysis Report';
            app.StartButton = uibutton(app.FLEETMANAGERPROV40Panel_2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.WaitLabel = uilabel(app.FLEETMANAGERPROV40Panel_2);
            app.WaitLabel.Text = '→ Wait';
            app.SimulationAreaPanel = uipanel(app.GridLayout);
            app.SimulationAreaPanel.Title = 'Simulation Area';
            app.UIAxes = uiaxes(app.SimulationAreaPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SIMTIME00sLabel = uilabel(app.SimulationAreaPanel);
            app.SIMTIME00sLabel.Text = 'SIM TIME: 0.0 s';
            app.DEADLOCKS0Label = uilabel(app.SimulationAreaPanel);
            app.DEADLOCKS0Label.Text = 'DEADLOCKS: 0';
            app.COMPLETED010Label = uilabel(app.SimulationAreaPanel);
            app.COMPLETED010Label.Text = 'COMPLETED: 0 / 10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end